classdef Praxisprojekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AlspngsichernCheckBox           matlab.ui.control.CheckBox
        AlsfigsichernCheckBox           matlab.ui.control.CheckBox
        LinksButton                     matlab.ui.control.Button
        RechtsButton                    matlab.ui.control.Button
        PlottenButton                   matlab.ui.control.Button
        SeitenanordnungausgewhltesSignalLabel  matlab.ui.control.Label
        SignalederGesamtmessdateiListBox  matlab.ui.control.ListBox
        SignalederGesamtmessdateiListBoxLabel  matlab.ui.control.Label
        RechteSeiteListBox              matlab.ui.control.ListBox
        RechteSeiteListBoxLabel         matlab.ui.control.Label
        LinkeSeiteListBox               matlab.ui.control.ListBox
        LinkeSeiteLabel                 matlab.ui.control.Label
        NameGesamtdateiEditField        matlab.ui.control.EditField
        NameGesamtdateiEditFieldLabel   matlab.ui.control.Label
        ZusammenfgenundResamplenButton  matlab.ui.control.Button
        AusgewhlteDateienListBox        matlab.ui.control.ListBox
        AusgewhlteDateienListBoxLabel   matlab.ui.control.Label
        SollAbtastfreuqenzEditField     matlab.ui.control.NumericEditField
        SollAbtastfreuqenzEditFieldLabel  matlab.ui.control.Label
        DateienauswhlenButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path
        new_measurements
        file_name
        markers = ['x'; '.'; '+'; '*'; 's'; 'd'; '^'; 'v'; '<'; '>'; 'p'; 'h'] %Markers welche nacheinander verwendet werden sollen
    end
    
    methods (Access = private)
        
        function combine_resample_safe(app, measurements, frequency, path)
        %COMBINE_RESAMPLE_SAFE - Zeit-kontinuierliche Kombination der Einzelmessungen zu einer 
        %Gesamtmessung mit einer vorgegeben Abtastfrquenz und speichern der Ergebnisses am vorgegebenen Pfad
        %
        % Syntax:  combine_resample_safe(app, measurements, frequency, path)
        %
        % Inputs:
        %    app - Aktuelle App   
        %    measurements - Einzelmessungen mit je allen Signalen als Cell-Array
        %    frequency - Einheitliche Frequenz auf die alle Signale geresampled werden als Double
        %    path - Pfad an dem das Ergebniss gespeichert werden soll als String oder Char-Array
        %
        % Outputs:
        %    none
        %
        % Example: 
        %    combine_resample_safe(app, {messung1, messung2, messung3, messung4}, 100, dir_path)
        %
        % Other m-files required: combine_resample.m
        % MAT-files required: none
        % Subfunctions: none
        %
        % See also: clean_data.m, extract_samplerates.m
        % Author: 1319658
        % June 2021; Last revision: 04-June-2021
        %------------- BEGIN CODE --------------
            if(frequency>0) %Gültige Frequenz
                progress = uiprogressdlg(app.UIFigure, 'Title', 'Bitte Warten');
                
                app.new_measurements = combine_resample(measurements, frequency, progress); 
                
                %Abspeichern
                measurement = app.new_measurements; %Benötigt um abzuspeichern
                save(path, "measurement");
                clear('measurement'); 
                
                %Nachricht an Nutzer
                path_elements = strsplit(path,filesep);
                message = strcat("Gesamtmessdatei erfolgreich unter dem Namen ", path_elements(end) , " erstellt");
                uialert(app.UIFigure, message, 'Erfolgreich', 'Icon', 'success');
                
                %Signalnamen alphabetisch sortieren
                signal_names = sort(fieldnames(app.new_measurements)); %get fieldnames and sort alphabetically
                signal_names = signal_names(~strcmp(signal_names, 'name')); %Delete 'name'-field
                
                app.LinkeSeiteListBox.Items = {};
                app.RechteSeiteListBox.Items = {};                
                clear_axes(app);
                app.SignalederGesamtmessdateiListBox.Items = signal_names; %Signalnamen in GesamtmessdateiListBox anzeigen 
                app.PlottenButton.Enable = true;      
                close(progress);
            else
                uialert(app.UIFigure, "Bitte gültige Frequenz eingeben!", 'Invalide Auswahl');
            end
        end
        %------------- END OF CODE --------------
        
        function add_signal_to_side(app, listbox_add)
        %ADD_SIGNAL_TO_SIDE - Ausgewähltes Signal der Gesamtdateilistbox zu der übergebenen Listbox hinzufügen
        %
        % Syntax:  add_signal_to_side(app, listbox_add)
        %
        % Inputs:
        %    app - Aktuelle App
        %    listbox_add - Listbox zu der das Signal hinzugefügt werden soll
        %
        % Outputs:
        %    none
        %
        % Example: 
        %    add_signal_to_side(app, app.ListBoxTest)
        %
        % Other m-files required: none
        % MAT-files required: none
        % Subfunctions: none
        %
        % See also: none
        % Author: 1319658
        % June 2021; Last revision: 04-June-2021
        %------------- BEGIN CODE --------------   
            value = app.SignalederGesamtmessdateiListBox.Value; %Aktuelle Auswahl
            if(~strcmp(value,'')) %Wert ausgewählt
                listbox_add.Items = [listbox_add.Items value]; %In gegebene ListBox hinzufügen
                %Signal aus GesamtmessdateiListBox entfernen und Auswahl zurücksetzen (sonst Doppelklick Gefahr)
                app.SignalederGesamtmessdateiListBox.Items = app.SignalederGesamtmessdateiListBox.Items(~strcmp(app.SignalederGesamtmessdateiListBox.Items, value));
                app.SignalederGesamtmessdateiListBox.Value = {};
            end
        end
        %------------- END OF CODE --------------
        
        function plot_signals(app, side, listbox, x_values)
        %PLOT_SIGNALS - Signale der übergebenen Listbox auf jeweilige y-Achsen-Seite plotten mit einem einheitlichen Zeitvektor für die x-Achse
        %
        % Syntax:  plot_signals(app, side, listbox, xValues)
        %
        % Inputs:
        %    app - Aktuelle App
        %    side - Seite der y-Achsen 'left' oder 'right' 
        %    listbox - Listbox mit Namen der zu plottenden Signale
        %    x_values - Einheitlicher Zeitvektor für alle Signale
        %
        % Outputs:
        %    none
        %
        % Example: 
        %    plot_signals(app, 'left', app.ListBoxTest, [1, 2, 3, 4, 5])
        %
        % Other m-files required: none
        % MAT-files required: none
        % Subfunctions: none
        %
        % See also: none
        % Author: 1319658
        % June 2021; Last revision: 04-June-2021
        %------------- BEGIN CODE --------------    
            yyaxis(app.UIAxes, side); %Y-Achse auswählen
            cla(app.UIAxes); %Alte Signale entfernen
            signals = cell2mat(cellfun(@(x) app.new_measurements.(x), listbox.Items, 'UniformOutput', false)'); %Neue Signale   
            marker_index = 0;
            for i = 1:size(signals,1) %Alle Signale plotten
                %Durch Markers cyclen wenn bereits alle einmal verwendet  
                marker_index = marker_index + 1;
                plot(app.UIAxes,x_values,signals(i,:),[app.markers(marker_index) '-'], 'MarkerSize', 5);
                marker_index = mod(i, length(app.markers));
            end        
        end
        %------------- END OF CODE --------------
        
        function clear_axes(app)
        %CLEAR_AXES - Plots und Daten in UIAxes löschen und Beschriftungen zurücksetzen
        %
        % Syntax:  clear_axes(app)
        %
        % Inputs:
        %    app - Aktuelle App
        %
        % Outputs:
        %    none
        %
        % Example: 
        %    clear_axes(app)
        %
        % Other m-files required: none
        % MAT-files required: none
        % Subfunctions: none
        %
        % See also: none
        % Author: 1319658
        % June 2021; Last revision: 04-June-2021
        %------------- BEGIN CODE --------------    
            %Inhalt löschen
            yyaxis(app.UIAxes, 'left');
            cla(app.UIAxes);
            yyaxis(app.UIAxes, 'right');
            cla(app.UIAxes);
            %Defaultwerte setzen
            grid(app.UIAxes, 'on');
            app.UIAxes.Title.String = "Dateiname";
            app.UIAxes.XLabel.String = "Zeit in Sekunden";
        end
        %------------- END OF CODE --------------
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: DateienauswhlenButton
        function DateienauswhlenButtonPushed(app, event)
        %DATEIENAUSWHLENBUTTONPUSHED - Funktion um Dateien welche geladen werden sollen aus Filebrowser auszuwählen
        %
        % Syntax:  DateienauswhlenButtonPushed(app, event)
        %
        % Inputs:
        %    app - Aktuelle App   
        %    event - Event welches Funktionsaufruf ausgelöst hat
        %
        % Outputs:
        %    none
        %
        % Example: 
        %    DateienauswhlenButtonPushed(app, event)
        %
        % Other m-files required: none
        % MAT-files required: none
        % Subfunctions: none
        %
        % See also: none
        % Author: 1319658
        % June 2021; Last revision: 04-June-2021
        %------------- BEGIN CODE --------------
            [files_import,path_import] = uigetfile('*.mat', 'Messfiles wählen', 'Choose File','MultiSelect', 'on');           
            if(isnumeric(files_import)) %Keine Auswahl getroffen
                return; 
            end
            if(~iscell(files_import)) %Wenn nur ein File ausgewählt
                files_import = cellstr(files_import);
            end
            %Auswahl speichern
            app.AusgewhlteDateienListBox.Items = files_import;
            app.AusgewhlteDateienListBox.ItemsData = strcat(path_import, filesep, files_import);
            app.path = path_import;
        %------------- END OF CODE --------------
        end

        % Button pushed function: ZusammenfgenundResamplenButton
        function ZusammenfgenundResamplenButtonPushed(app, event)
        %ZUSAMMENFGENUNDRESAMPLENBUTTONPUSHED - Funktion um das aufbereiten der geladenen Messungen zu steuern
        %
        % Syntax:  ZusammenfgenundResamplenButtonPushed(app, event)
        %
        % Inputs:
        %    app - Aktuelle App   
        %    event - Event welches Funktionsaufruf ausgelöst hat
        %
        % Outputs:
        %    none
        %
        % Example: 
        %    ZusammenfgenundResamplenButtonPushed(app, event)
        %
        % Other m-files required: combine_resample.m
        % MAT-files required: none
        % Subfunctions: none
        %
        % See also: clean_data.m, extract_samplerates.m
        % Author: 1319658
        % June 2021; Last revision: 04-June-2021
        %------------- BEGIN CODE --------------
            if(~isempty(app.AusgewhlteDateienListBox.Items)) %Bereits Files ausgewählt
                
                %Elemente disablen, Fehlfunktionen vermeiden
                app.DateienauswhlenButton.Enable = false;
                app.SollAbtastfreuqenzEditField.Enable = false;
                app.NameGesamtdateiEditField.Enable = false;
                app.ZusammenfgenundResamplenButton.Enable = false; 
                pause(0.001);
                
                measurements = cellfun(@(x) load(x).measurement, app.AusgewhlteDateienListBox.ItemsData, 'UniformOutput', false); %Messungen aus Files extrahieren                  
                if(endsWith(app.NameGesamtdateiEditField.Value, '.mat')) %Name bereits gültig für .mat-File
                    app.file_name = app.NameGesamtdateiEditField.Value; %Dateinamen speichern
                    full_path = strcat(app.path, app.NameGesamtdateiEditField.Value); %Pfad bauen
                    combine_resample_safe(app,measurements, app.SollAbtastfreuqenzEditField.Value, full_path);                    
                else %Name nicht gültig
                    if(~strcmp(app.NameGesamtdateiEditField.Value, "")) %Feld befüllt
                       app.file_name = strcat(app.NameGesamtdateiEditField.Value, ".mat"); %An eingegebenen Namen .mat anfügen
                       full_path = strcat(app.path, app.file_name); %Pfad bauen
                       combine_resample_safe(app,measurements, app.SollAbtastfreuqenzEditField.Value, full_path);
                    else %Feld leer
                        uialert(app.UIFigure, "Bitte Dateinamen eingeben!", 'Invalide Auswahl');
                    end                    
                end
                
                %Elemente enablen
                app.DateienauswhlenButton.Enable = true;
                app.SollAbtastfreuqenzEditField.Enable = true;
                app.NameGesamtdateiEditField.Enable = true;
                app.ZusammenfgenundResamplenButton.Enable = true;
            else
                uialert(app.UIFigure, "Keine Dateien ausgewählt! Dateien mit 'Dateien auswählen' auswählen.", 'Invalide Auswahl');
            end
        %------------- END OF CODE --------------
        end

        % Button pushed function: LinksButton
        function LinksButtonPushed(app, event)
            %Signal zur linken ListBox hinzufügen
            add_signal_to_side(app, app.LinkeSeiteListBox);
        end

        % Button pushed function: RechtsButton
        function RechtsButtonPushed(app, event)
            %Signal zur rechten ListBox hinzufügen
            add_signal_to_side(app, app.RechteSeiteListBox);
        end

        % Button pushed function: PlottenButton
        function PlottenButtonPushed(app, event)
        %PLOTTENBUTTONPUSHED - Funktion um aufbereitete Daten darzustellen und zu speichern
        %
        % Syntax:  PlottenButtonPushed(app, event)
        %
        % Inputs:
        %    app - Aktuelle App   
        %    event - Event welches Funktionsaufruf ausgelöst hat
        %
        % Outputs:
        %    none
        %
        % Example: 
        %    PlottenButtonPushed(app, event)
        %
        % Other m-files required: none
        % MAT-files required: none
        % Subfunctions: none
        %
        % See also: none
        % Author: 1319658
        % June 2021; Last revision: 04-June-2021
        %------------- BEGIN CODE --------------
        %Elemente disablen, Fehlfunktionen vermeiden
            app.AlspngsichernCheckBox.Enable = false;
            app.AlsfigsichernCheckBox.Enable = false;
            app.LinksButton.Enable = false;
            app.RechtsButton.Enable = false;
            app.PlottenButton.Enable = false;
            pause(0.001);
            
            if(~isempty(app.new_measurements)) %Bereits Messungen bearbeitet
                signal_names = fieldnames(app.new_measurements); %Signalnamen
                time_signal_name = signal_names{startsWith(signal_names, 'unified_time_Timestamp')}; %Name des gemeinsamen Zeitvektors
                time = app.new_measurements.(time_signal_name); %Gemeinsamer Zeitvektor
                
                %Beschriftungen
                app.UIAxes.Title.String = app.file_name; %Name der Datei als Titel
                xlabel(app.UIAxes, strcat("Zeit in Sekunden (Signal: ", time_signal_name,")"), 'interpreter', 'none'); %X-Achsenbeschriftung mit Name des gemeinsamen Zeitvektors
                
                %Alle Signale plotten
                hold(app.UIAxes, 'on');
                plot_signals(app, 'left', app.LinkeSeiteListBox, time); %Signale für linke Y-Achse plotten
                plot_signals(app, 'right', app.RechteSeiteListBox, time); %Signale für rechte Y-Achse plotten
                
                %Legende erstellen
                legend(app.UIAxes, [app.LinkeSeiteListBox.Items, app.RechteSeiteListBox.Items], 'interpreter', 'none');
                hold(app.UIAxes, 'off');
                
                %Überprüfen ob und wenn ja unter welchen Signalen der Plot gespeichert werden soll
                if(app.AlspngsichernCheckBox.Value) %Als PNG sichern
                    name = strcat(app.path, filesep, strrep(app.file_name, '.mat', ''), '_plot.png'); %Pfadnamen bauen
                    exportgraphics(app.UIAxes, name);
                end
                if(app.AlsfigsichernCheckBox.Value) %Als FIG sichern
                    name = strcat(app.path, filesep, strrep(app.file_name, '.mat', ''), '_plot'); %Pfadnamen bauen
                    %Fig sichern
                    signals_plot = figure('Visible',"off");
                    copyUIAxes(app.UIAxes, signals_plot);
                    savefig(name);
                    close(signals_plot);
                end
            end
            
            %Elemente enablen
            app.AlspngsichernCheckBox.Enable = true;
            app.AlsfigsichernCheckBox.Enable = true;  
            app.LinksButton.Enable = true;
            app.RechtsButton.Enable = true;
            app.PlottenButton.Enable = true;
        %------------- END OF CODE --------------
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dateiname')
            xlabel(app.UIAxes, 'Zeit in Sekunden')
            zlabel(app.UIAxes, 'Z')
            app.DateienauswhlenButton = uibutton(app.UIFigure, 'push');
            app.DateienauswhlenButton.ButtonPushedFcn = createCallbackFcn(app, @DateienauswhlenButtonPushed, true);
            app.DateienauswhlenButton.Text = 'Dateien auswählen';
            app.SollAbtastfreuqenzEditFieldLabel = uilabel(app.UIFigure);
            app.SollAbtastfreuqenzEditFieldLabel.Text = 'Soll-Abtastfreuqenz';
            app.SollAbtastfreuqenzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SollAbtastfreuqenzEditField.Limits = [0 Inf];
            app.SollAbtastfreuqenzEditField.Value = 10;
            app.AusgewhlteDateienListBoxLabel = uilabel(app.UIFigure);
            app.AusgewhlteDateienListBoxLabel.Text = 'Ausgewählte Dateien';
            app.AusgewhlteDateienListBox = uilistbox(app.UIFigure);
            app.AusgewhlteDateienListBox.Items = {};
            app.AusgewhlteDateienListBox.Value = {};
            app.ZusammenfgenundResamplenButton = uibutton(app.UIFigure, 'push');
            app.ZusammenfgenundResamplenButton.ButtonPushedFcn = createCallbackFcn(app, @ZusammenfgenundResamplenButtonPushed, true);
            app.ZusammenfgenundResamplenButton.Text = 'Zusammenfügen und Resamplen';
            app.NameGesamtdateiEditFieldLabel = uilabel(app.UIFigure);
            app.NameGesamtdateiEditFieldLabel.Text = 'Name Gesamtdatei';
            app.NameGesamtdateiEditField = uieditfield(app.UIFigure, 'text');
            app.NameGesamtdateiEditField.Value = 'ResultData.mat';
            app.LinkeSeiteLabel = uilabel(app.UIFigure);
            app.LinkeSeiteLabel.Text = 'Linke Seite';
            app.LinkeSeiteListBox = uilistbox(app.UIFigure);
            app.LinkeSeiteListBox.Items = {};
            app.LinkeSeiteListBox.Value = {};
            app.RechteSeiteListBoxLabel = uilabel(app.UIFigure);
            app.RechteSeiteListBoxLabel.Text = 'Rechte Seite';
            app.RechteSeiteListBox = uilistbox(app.UIFigure);
            app.RechteSeiteListBox.Items = {};
            app.RechteSeiteListBox.Value = {};
            app.SignalederGesamtmessdateiListBoxLabel = uilabel(app.UIFigure);
            app.SignalederGesamtmessdateiListBoxLabel.Text = 'Signale der Gesamtmessdatei';
            app.SignalederGesamtmessdateiListBox = uilistbox(app.UIFigure);
            app.SignalederGesamtmessdateiListBox.Items = {};
            app.SignalederGesamtmessdateiListBox.Value = {};
            app.SeitenanordnungausgewhltesSignalLabel = uilabel(app.UIFigure);
            app.SeitenanordnungausgewhltesSignalLabel.Text = '  Seitenanordnung ausgewähltes Signal:';
            app.PlottenButton = uibutton(app.UIFigure, 'push');
            app.PlottenButton.ButtonPushedFcn = createCallbackFcn(app, @PlottenButtonPushed, true);
            app.PlottenButton.Enable = 'off';
            app.PlottenButton.Text = 'Plotten';
            app.RechtsButton = uibutton(app.UIFigure, 'push');
            app.RechtsButton.ButtonPushedFcn = createCallbackFcn(app, @RechtsButtonPushed, true);
            app.RechtsButton.Text = 'Rechts';
            app.LinksButton = uibutton(app.UIFigure, 'push');
            app.LinksButton.ButtonPushedFcn = createCallbackFcn(app, @LinksButtonPushed, true);
            app.LinksButton.Text = 'Links';
            app.AlsfigsichernCheckBox = uicheckbox(app.UIFigure);
            app.AlsfigsichernCheckBox.Text = 'Als *.fig sichern';
            app.AlspngsichernCheckBox = uicheckbox(app.UIFigure);
            app.AlspngsichernCheckBox.Text = 'Als *.png sichern';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Praxisprojekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end